classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CapturarTableroButton      matlab.ui.control.StateButton
        CalibrarCamButton          matlab.ui.control.StateButton
        ClasificarButton           matlab.ui.control.StateButton
        CapturarPiezasButton       matlab.ui.control.StateButton
        InicioButton               matlab.ui.control.Button
        ParadaButton               matlab.ui.control.Button
        OperacionLampLabel         matlab.ui.control.Label
        OperacionLamp              matlab.ui.control.Lamp
        PausaLampLabel             matlab.ui.control.Label
        PausaLamp                  matlab.ui.control.Lamp
        ParadaLabel                matlab.ui.control.Label
        ParadaLamp                 matlab.ui.control.Lamp
        Phantom1Panel              matlab.ui.container.Panel
        EspaciodelasconfiguracionesLabel  matlab.ui.control.Label
        q1Label                    matlab.ui.control.Label
        q1                         matlab.ui.control.Label
        q2Label                    matlab.ui.control.Label
        q2                         matlab.ui.control.Label
        q3Label                    matlab.ui.control.Label
        q3                         matlab.ui.control.Label
        q4Label                    matlab.ui.control.Label
        q4                         matlab.ui.control.Label
        EspaciooperacionalLabel    matlab.ui.control.Label
        xLabel                     matlab.ui.control.Label
        x                          matlab.ui.control.Label
        yLabel                     matlab.ui.control.Label
        y                          matlab.ui.control.Label
        zLabel                     matlab.ui.control.Label
        z                          matlab.ui.control.Label
        RLabel                     matlab.ui.control.Label
        R                          matlab.ui.control.Label
        PLabel                     matlab.ui.control.Label
        P                          matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        Y                          matlab.ui.control.Label
        Phantom2Panel              matlab.ui.container.Panel
        EspaciodelasconfiguracionesLabel_2  matlab.ui.control.Label
        q1Label_2                  matlab.ui.control.Label
        q2Label_2                  matlab.ui.control.Label
        q2_2                       matlab.ui.control.Label
        q3Label_2                  matlab.ui.control.Label
        q3_2                       matlab.ui.control.Label
        q4Label_2                  matlab.ui.control.Label
        q4_2                       matlab.ui.control.Label
        EspaciooperacionalLabel_2  matlab.ui.control.Label
        xLabel_2                   matlab.ui.control.Label
        x2                         matlab.ui.control.Label
        yLabel_2                   matlab.ui.control.Label
        y2                         matlab.ui.control.Label
        zLabel_2                   matlab.ui.control.Label
        z2                         matlab.ui.control.Label
        RLabel_2                   matlab.ui.control.Label
        R2                         matlab.ui.control.Label
        PLabel_2                   matlab.ui.control.Label
        P2                         matlab.ui.control.Label
        YLabel_2                   matlab.ui.control.Label
        Y2                         matlab.ui.control.Label
        q1_2                       matlab.ui.control.Label
        TornilloPanel              matlab.ui.container.Panel
        xLabel_3                   matlab.ui.control.Label
        xtor                       matlab.ui.control.Label
        yLabel_3                   matlab.ui.control.Label
        ytor                       matlab.ui.control.Label
        RestantesLabel             matlab.ui.control.Label
        ColocadosLabel             matlab.ui.control.Label
        TornilloRestantes          matlab.ui.control.Label
        TornillosColocados         matlab.ui.control.Label
        TuercaPanel                matlab.ui.container.Panel
        xLabel_4                   matlab.ui.control.Label
        xtuer                      matlab.ui.control.Label
        yLabel_4                   matlab.ui.control.Label
        ytuer                      matlab.ui.control.Label
        RestantesLabel_2           matlab.ui.control.Label
        ColocadosLabel_2           matlab.ui.control.Label
        TuercasRestantes           matlab.ui.control.Label
        TuercasColocadas           matlab.ui.control.Label
        LampTablero                matlab.ui.control.Lamp
        LampPiezas                 matlab.ui.control.Lamp
        LampCam                    matlab.ui.control.Lamp
        LampClas                   matlab.ui.control.Lamp
        Ensamble                   matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Ipiezas % Description
        params
        estimationErrors
        RCal
        TCal
        xyTornillos = [0.075 0.065;0.05 0.05]
        xyTuercas = [0.075 0.065;0.05 0.05;0.02 0.06]
        StopNow = false
        Ic
        Itrim %Imagen Cortada
        worldMax % Tamaño del espacio de referencia
        centrTuercas
        centrTornillos
        target
        Path= "/home/julian/Documents/GitHub/Proyecto_Intermedio_Robotica_2020_2/Scripts Matlab";       
        
    end
    
    methods (Access = private)
        
        function rutina(app,xyTornillos,xyTuercas)
            s = size(xyTornillos);
            tor = s(1);
            s = size(xyTuercas);
            tuer = s(1);
            if tor >= tuer
                numax = tor;
            else
                numax = tuer;
            end
            
            
            for i = 1:numax
                
                if i<=tor
                    app.xtor.Text = num2str(xyTornillos(i,1));
                    app.ytor.Text = num2str(xyTornillos(i,2));
                    rutina_robot1(app,xyTornillos(i,1),xyTornillos(i,2))
                    app.TornilloRestantes.Text = num2str(tor-i);
                    app.TornillosColocados.Text =  num2str(str2num(app.TornillosColocados.Text) + 1);
                end
                
                if i<=tuer
                    app.xtuer.Text = num2str(xyTuercas(i,1));
                    app.ytuer.Text = num2str(xyTuercas(i,2));
                    rutina_robot2(app,xyTuercas(i,1),xyTuercas(i,2))
                    app.TuercasRestantes.Text = num2str(tuer-i);
                    app.TuercasColocadas.Text = num2str(str2num(app.TuercasColocadas.Text) + 1);
                end
            end
        end
        
        function rutina_robot1(app,entrada_x,entrada_y)
            
            % Definición del robot
            L1(1) = Link('revolute','alpha',0,'a',0,'d',0.137,'offset',0,'modified');
            L1(2) = Link('revolute','alpha',-pi/2,'a',0,'d',0,'offset',-pi/2,'modified');
            L1(3) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            L1(4) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            
            R_tool = [0  0  1;
                -1  0  0;
                0 -1  0];
            P_tool = [0.11 0 0]';
            T_4_tool = rt2tr(R_tool,P_tool);
            R1 = SerialLink(L1,'name','phantom1');
            R1.tool = T_4_tool;
            
            
            % Posiciones fijas
            qi(1,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(6,:) = [-0.6476    1.1058    0.2157    1.8202    0.0160    0.0160];
            qi(7,:) = [-0.6476    1.1833    0.7443    1.2140    0.0160    0.0160];
            qi(8,:) = [-0.6476    1.1833    0.7443    1.2140         0         0];
            qi(9,:) = [-0.6476    1.1057    0.2157    1.8201         0         0];
            qi(10,:) = qi(1,:);
            
            
            % Marco de referencia de la camara a sistema absoluto
            x = 0.053 + entrada_x;
            y = 0.040 + entrada_y;
            
            % MTH del tool
            R_tool = [  1  0  0;
                0 -1  0;
                0  0 -1];
            
            % Punto de aproximación
            P = [x,y,0.1]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(atan2(P(2),P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(2,:) = [R1.ikunc(MTH,qi(1,1:4)) 0 0]; % Llamamos la función del toolbox
            
            % Punto de recogida
            P = [x,y,0.03]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(atan2(P(2),P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(3,:) = [R1.ikunc(MTH,qi(2,1:4)) 0 0]; % Llamamos la función del toolbox
            
            % Cierre del griper
            qi(4,:) = [qi(3,1:4) 0.016 0.016];
            
            % Punto de elevación
            P = [x,y,0.15]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(atan2(P(2),P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(5,:) = [R1.ikunc(MTH,qi(4,1:4)) 0.016 0.016];
            mover_gazebo(app,qi,'1',R1)
        end
        
        function rutina_robot2(app,entrada_x,entrada_y)
            
            d = 0.42; % Distancia entre robots
            
            % Definición del robot
            L2(1) = Link('revolute','alpha',0,'a',d,'d',0.137,'offset',pi,'modified');
            L2(2) = Link('revolute','alpha',-pi/2,'a',0,'d',0,'offset',-pi/2,'qlim',[-pi/2 30*pi/180] ,'modified');
            L2(3) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            L2(4) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            
            R_tool = [0  0  1;
                -1  0  0;
                0 -1  0];
            P_tool = [0.11 0 0]';
            T_4_tool = rt2tr(R_tool,P_tool);
            R2 = SerialLink(L2,'name','phantom2');
            R2.tool = T_4_tool;
            
            % Posiciones fijas
            qi(1,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(6,:) = [0.7056    0.9746    0.0000    2.1607    0.0160    0.0160];
            qi(7,:) = [0.7056    0.8976    0.9875    1.2565    0.0160    0.0160];
            qi(8,:) = [0.7056    0.8976    0.9875    1.2565         0         0];
            qi(9,:) = [0.7056    0.9746    0.0000    2.1607         0         0];
            qi(10,:) = qi(1,:);
            
            % Marco de referencia de la camara a sistema absoluto
            x = 0.225 + entrada_x;
            y = 0.040 + entrada_y;
            
            % MTH del tool
            R_tool = [ -1  0  0;
                0  1  0;
                0  0 -1];
            
            % Punto de aproximación
            P = [x,y,0.1]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(-atan2(P(2),d-P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(2,:) = [R2.ikunc(MTH,qi(1,1:4)) 0 0]; % Llamamos la función del toolbox del toolbox
            
            % Punto de recogida
            P = [x,y,0.03]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(-atan2(P(2),d-P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(3,:) = [R2.ikunc(MTH,qi(2,1:4)) 0 0]; %Llamamos la función del toolbox
            
            % Cierre del griper
            qi(4,:) = [qi(3,1:4) 0.016 0.016];
            
            % Punto de elevación
            P = [x,y,0.15]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(-atan2(P(2),d-P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(5,:) = [R2.ikunc(MTH,qi(4,1:4)) 0.016 0.016];
            mover_gazebo(app,qi,'2',R2)
        end
        
        function mover_gazebo(app,qi,num,R)
            rosinit
            phantompos = rospublisher("/robot_"+num+"/phantom_position_controller/command");
            message = rosmessage(phantompos);
            positionreal = rossubscriber("/robot_"+num+"/joint_states");
            imagen = rossubscriber("/camara/rrbot/camera1/image_raw");
            pause(1);
            
            for i = 1:length(qi)
                if app.StopNow
                    break
                end
                message.Data = qi(i,:);
                send(phantompos,message);
                states = receive(positionreal,2).Position;
                states = round([states(3:6)' states(1:2)'],2);
                while  ~isequal( states, round(qi(i,:),2) )
                    if app.StopNow
                        break
                    end
                    states = receive(positionreal,1).Position;
                    states = round([states(3:6)' states(1:2)'],2);
                    MTH = R.fkine(states(1:4));
                    Pos = round(MTH(1:3,4),2);
                    Ori = round(rad2deg(tr2rpy(MTH)),2);
                    if num == '1'
                        app.q1.Text = num2str(states(1));
                        app.q2.Text = num2str(states(2));
                        app.q3.Text = num2str(states(3));
                        app.q4.Text = num2str(states(4));
                        app.x.Text = num2str(Pos(1));
                        app.y.Text = num2str(Pos(2));
                        app.z.Text = num2str(Pos(3));
                        app.R.Text = num2str(Ori(1));
                        app.P.Text = num2str(Ori(2));
                        app.Y.Text = num2str(Ori(3));
                    else
                        app.q1_2.Text = num2str(states(1));
                        app.q2_2.Text = num2str(states(2));
                        app.q3_2.Text = num2str(states(3));
                        app.q4_2.Text = num2str(states(4));
                        app.x2.Text = num2str(Pos(1));
                        app.y2.Text = num2str(Pos(2));
                        app.z2.Text = num2str(Pos(3));
                        app.R2.Text = num2str(Ori(1));
                        app.P2.Text = num2str(Ori(2));
                        app.Y2.Text = num2str(Ori(3));
                        
                    end
                    imagennueva = receive(imagen,2);
                    imshow(imagennueva.readImage(),'parent',app.UIAxes_3);
                    
                end
            end
            
            rosshutdown
        end
        
        function ensamble(app,xyTornillos,xyTuercas)
            s = size(xyTornillos);
            tor = s(1);
            s = size(xyTuercas);
            tuer = s(1);
            if tor >= tuer
                numax = tor;
            else
                numax = tuer;
            end
            
            
            for i = 1:numax
                if i<=tor && i<=tuer
                    app.xtor.Text = num2str(xyTornillos(i,1));
                    app.ytor.Text = num2str(xyTornillos(i,2));
                    app.xtuer.Text = num2str(xyTuercas(i,1));
                    app.ytuer.Text = num2str(xyTuercas(i,2));
                    [qi,R1] = robot1(app,xyTornillos(i,1),xyTornillos(i,2));
                    [qi2,R2] = robot2(app,xyTuercas(i,1),xyTuercas(i,2));
                    mover_gazebo2(app,qi,qi2,R1,R2);
                    app.TornilloRestantes.Text = num2str(tor-i);
                    app.TornillosColocados.Text =  num2str(str2num(app.TornillosColocados.Text) + 1);
                    app.TuercasRestantes.Text = num2str(tuer-i);
                    app.TuercasColocadas.Text = num2str(str2num(app.TuercasColocadas.Text) + 1);
                else
                    if i<=tor
                        app.xtor.Text = num2str(xyTornillos(i,1));
                        app.ytor.Text = num2str(xyTornillos(i,2));
                        rutina_robot1(app,xyTornillos(i,1),xyTornillos(i,2))
                        app.TornilloRestantes.Text = num2str(tor-i);
                        app.TornillosColocados.Text =  num2str(str2num(app.TornillosColocados.Text) + 1);
                    end
                    
                    if i<=tuer
                        app.xtuer.Text = num2str(xyTuercas(i,1));
                        app.ytuer.Text = num2str(xyTuercas(i,2));
                        rutina_robot2(app,xyTuercas(i,1),xyTuercas(i,2))
                        app.TuercasRestantes.Text = num2str(tuer-i);
                        app.TuercasColocadas.Text = num2str(str2num(app.TuercasColocadas.Text) + 1);
                    end
                    
                end
                
            
            end
        end
        
        function [qi,R1] = robot1(app,entrada_x,entrada_y)
            
            % Definición del robot
            L1(1) = Link('revolute','alpha',0,'a',0,'d',0.137,'offset',0,'modified');
            L1(2) = Link('revolute','alpha',-pi/2,'a',0,'d',0,'offset',-pi/2,'modified');
            L1(3) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            L1(4) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            
            R_tool = [0  0  1;
                -1  0  0;
                0 -1  0];
            P_tool = [0.11 0 0]';
            T_4_tool = rt2tr(R_tool,P_tool);
            R1 = SerialLink(L1,'name','phantom1');
            R1.tool = T_4_tool;
            
            
            % Posiciones fijas
            qi(1,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(6,:) = [0   -0.2227    1.9794   -0.1859  0.0160    0.0160];
            qi(7,:) = [0   -0.2227    1.9794   -0.1859  0.0160    0.0160];
            qi(8,:) = [0   -0.2227    1.9794   -0.1859  0.0160    0.0160];
            qi(9,:) = [-0.6476    1.1058    0.2157    1.8202    0.0160    0.0160];
            qi(10,:) = [-0.6476    1.1833    0.7443    1.2140    0.0160    0.0160];
            qi(11,:) = [-0.6476    1.1833    0.7443    1.2140         0         0];
            qi(12,:) = [-0.6476    1.1057    0.2157    1.8201         0         0];
            qi(13,:) = qi(1,:);
            
            
            % Marco de referencia de la camara a sistema absoluto
            x = 0.053 + entrada_x;
            y = 0.040 + entrada_y;
            
            % MTH del tool
            R_tool = [  1  0  0;
                0 -1  0;
                0  0 -1];
            
            % Punto de aproximación
            P = [x,y,0.1]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(atan2(P(2),P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(2,:) = [R1.ikunc(MTH,qi(1,1:4)) 0 0]; % Llamamos la función del toolbox
            
            % Punto de recogida
            P = [x,y,0.03]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(atan2(P(2),P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(3,:) = [R1.ikunc(MTH,qi(2,1:4)) 0 0]; % Llamamos la función del toolbox
            
            % Cierre del griper
            qi(4,:) = [qi(3,1:4) 0.016 0.016];
            
            % Punto de elevación
            P = [x,y,0.15]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(atan2(P(2),P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(5,:) = [R1.ikunc(MTH,qi(4,1:4)) 0.016 0.016];
        end
        
        function [qi,R2] = robot2(app,entrada_x,entrada_y)
            
            d = 0.42; % Distancia entre robots
            
            % Definición del robot
            L2(1) = Link('revolute','alpha',0,'a',d,'d',0.137,'offset',pi,'modified');
            L2(2) = Link('revolute','alpha',-pi/2,'a',0,'d',0,'offset',-pi/2,'qlim',[-pi/2 30*pi/180] ,'modified');
            L2(3) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            L2(4) = Link('revolute','alpha',0,'a',0.105,'d',0,'offset',0,'modified');
            
            R_tool = [0  0  1;
                -1  0  0;
                0 -1  0];
            P_tool = [0.11 0 0]';
            T_4_tool = rt2tr(R_tool,P_tool);
            R2 = SerialLink(L2,'name','phantom2');
            R2.tool = T_4_tool;
            
            % Posiciones fijas
            qi(1,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(6,:) = [0    0.4668    0.6160    0.4880    0.0160    0.0160];
            qi(7,:) = [0    0.3498    1.0838    0.1372    0.0160    0.0160];
            qi(8,:) = [0    0.3498    1.0838    0.1372   0    0];
            qi(9,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(10,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(11,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(12,:) = [0   -1.0000    2.4000    0.5000         0         0];
            qi(13,:) = qi(1,:);
            
            % Marco de referencia de la camara a sistema absoluto
            x = 0.225 + entrada_x;
            y = 0.040 + entrada_y;
            
            % MTH del tool
            R_tool = [ -1  0  0;
                0  1  0;
                0  0 -1];
            
            % Punto de aproximación
            P = [x,y,0.1]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(-atan2(P(2),d-P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(2,:) = [R2.ikunc(MTH,qi(1,1:4)) 0 0]; % Llamamos la función del toolbox del toolbox
            
            % Punto de recogida
            P = [x,y,0.03]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(-atan2(P(2),d-P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(3,:) = [R2.ikunc(MTH,qi(2,1:4)) 0 0]; %Llamamos la función del toolbox
            
            % Cierre del griper
            qi(4,:) = [qi(3,1:4) 0.016 0.016];
            
            % Punto de elevación
            P = [x,y,0.15]';
            
            % Rotación respecto a z para orientación del efector
            Rot = rotz(-atan2(P(2),d-P(1)))*R_tool;
            MTH = rt2tr(Rot, P);
            qi(5,:) = [R2.ikunc(MTH,qi(4,1:4)) 0.016 0.016];
        end
        
        function mover_gazebo2(app,qi,qi2,R1,R2)
            rosinit
            % Robot 1
            phantompos = rospublisher("/robot_1/phantom_position_controller/command");
            message = rosmessage(phantompos);
            positionreal = rossubscriber("/robot_1/joint_states");
            pause(1);
            
            % Robot 2
            phantom2pos = rospublisher("/robot_2/phantom_position_controller/command");
            message2 = rosmessage(phantompos);
            positionreal2 = rossubscriber("/robot_2/joint_states");
            pause(1);
            
            imagen = rossubscriber("/camara/rrbot/camera1/image_raw");
            
            for i = 1:length(qi)
                message.Data = qi(i,:);
                send(phantompos,message);
                states = receive(positionreal,2).Position;
                states = round([states(3:6)' states(1:2)'],2);
                
                message2.Data = qi2(i,:);
                send(phantom2pos,message2);
                states2 = receive(positionreal2,2).Position;
                states2 = round([states2(3:6)' states2(1:2)'],2);
                
                while  ~isequal( states, round(qi(i,:),2) ) || ~isequal( states2, round(qi2(i,:),2) )
                    % Robot 1
                    states = receive(positionreal,1).Position;
                    states = round([states(3:6)' states(1:2)'],2);
                    MTH = R1.fkine(states(1:4));
                    Pos = round(MTH(1:3,4),2);
                    Ori = round(rad2deg(tr2rpy(MTH)),2);
                    app.q1.Text = num2str(states(1));
                    app.q2.Text = num2str(states(2));
                    app.q3.Text = num2str(states(3));
                    app.q4.Text = num2str(states(4));
                    app.x.Text = num2str(Pos(1));
                    app.y.Text = num2str(Pos(2));
                    app.z.Text = num2str(Pos(3));
                    app.R.Text = num2str(Ori(1));
                    app.P.Text = num2str(Ori(2));
                    app.Y.Text = num2str(Ori(3));
                    
                    % Robot 2
                    states2 = receive(positionreal2,1).Position;
                    states2 = round([states2(3:6)' states2(1:2)'],2);
                    
                    MTH = R2.fkine(states2(1:4));
                    Pos = round(MTH(1:3,4),2);
                    Ori = round(rad2deg(tr2rpy(MTH)),2);
                    app.q1_2.Text = num2str(states2(1));
                    app.q2_2.Text = num2str(states2(2));
                    app.q3_2.Text = num2str(states2(3));
                    app.q4_2.Text = num2str(states2(4));
                    app.x2.Text = num2str(Pos(1));
                    app.y2.Text = num2str(Pos(2));
                    app.z2.Text = num2str(Pos(3));
                    app.R2.Text = num2str(Ori(1));
                    app.P2.Text = num2str(Ori(2));
                    app.Y2.Text = num2str(Ori(3));
                    
                    imagennueva = receive(imagen,2);
                    imshow(imagennueva.readImage(),'parent',app.UIAxes_3);
                end
            end
            
            rosshutdown
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Cargar parámetros de configuración e imagen para clasificación
            app.Ipiezas=imread(strcat(app.Path,'/Ipiezas.jpg'));
            VarParam=load(strcat(app.Path,'/params.mat'));
            paramStruct = toStruct(VarParam.params);
            app.params=cameraParameters(paramStruct);
            app.RCal=load(strcat(app.Path,'/RCal.mat')).RCal;
            app.TCal=load(strcat(app.Path,'/TCal.mat')).TCal;       
            app.worldMax=load(strcat(app.Path,'/worldMax.mat')).worldMax;
        end

        % Value changed function: CalibrarCamButton
        function CalibrarCamButtonValueChanged(app, event)
            app.LampCam.Color = [1 1 0];
            [app.params,app.estimationErrors,app.RCal,app.TCal,app.target,app.worldMax]=calibrar(app.Path);
            imshow(app.target,'parent',app.UIAxes);
            app.LampCam.Color = [0 1 0];
        end

        % Value changed function: ClasificarButton
        function ClasificarButtonValueChanged(app, event)
            app.LampClas.Color = [1 1 0];          
            [app.xyTuercas, app.xyTornillos,app.Ic,app.Itrim,app.centrTuercas,app.centrTornillos]=clasificar(app.Ipiezas,app.params,app.RCal,app.TCal,app.worldMax);
            %% Gráfico Clasificación
            n1 = size(app.xyTuercas);
            n2 = size(app.xyTornillos);    
            imshow(app.Itrim,'parent',app.UIAxes);            
            imshow(app.Ic,'parent',app.UIAxes_2);
            for n = 1:n1(1)
                text(app.centrTuercas(n,1),app.centrTuercas(n,2),'+','FontSize',20,'Color','r','parent',app.UIAxes_2,'HorizontalAlignment','center');
            end
            for n = 1:n2(1)
                text(app.centrTornillos(n,1),app.centrTornillos(n,2),'*','FontSize',20,'Color','r','parent',app.UIAxes_2,'HorizontalAlignment','center');
            end              
            app.TornilloRestantes.Text = num2str(n2(1));
            app.TuercasRestantes.Text = num2str(n1(1));
            app.LampClas.Color = [0 1 0];
            app.LampClas.Enable = true;
        end

        % Value changed function: CapturarPiezasButton
        function CapturarPiezasButtonValueChanged(app, event)
            app.LampPiezas.Color = [1 1 0];
            app.Ipiezas=captPiezas(app.Path);
            imshow(app.Ipiezas,'parent',app.UIAxes);
            app.LampPiezas.Color = [0 1 0];
        end

        % Callback function
        function PathTextAreaValueChanged(app, event)
            
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
            app.PausaLamp.Enable = false;
            app.ParadaLamp.Enable = false;
            app.OperacionLamp.Enable = true;
            app.StopNow = false;
            rutina(app,app.xyTornillos,app.xyTuercas)
            if ~app.StopNow
                app.OperacionLamp.Enable = false;
                app.PausaLamp.Enable = true;
            end
        end

        % Button pushed function: ParadaButton
        function ParadaButtonPushed(app, event)
            app.PausaLamp.Enable = false;
            app.OperacionLamp.Enable = false;
            app.StopNow = true;
            app.ParadaLamp.Enable = true;
        end

        % Value changed function: CapturarTableroButton
        function CapturarTableroButtonValueChanged(app, event)
            app.LampTablero.Color = [1 1 0];
            captICal(app.Path);
            app.Ipiezas = imread(strcat(app.Path,'/Calibracion/16.jpg'));
            imshow(app.Ipiezas,'parent',app.UIAxes);
            app.LampTablero.Color = [0 1 0];
        end

        % Button pushed function: Ensamble
        function EnsambleButtonPushed(app, event)
            app.PausaLamp.Enable = false;
            app.ParadaLamp.Enable = false;
            app.OperacionLamp.Enable = true;
            app.StopNow = false;
            ensamble(app,app.xyTornillos,app.xyTuercas)
            if ~app.StopNow
                app.OperacionLamp.Enable = false;
                app.PausaLamp.Enable = true;
            end
        end

        % Callback function
        function stopButtonPushed(app, event)
            app.StopNow = ~app.StopNow ;
            while app.StopNow == true 
                disp('1');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CapturarTableroButton = uibutton(app.UIFigure, 'state');
            app.CapturarTableroButton.ValueChangedFcn = createCallbackFcn(app, @CapturarTableroButtonValueChanged, true);
            app.CapturarTableroButton.Text = 'Capturar Tablero';
            app.CalibrarCamButton = uibutton(app.UIFigure, 'state');
            app.CalibrarCamButton.ValueChangedFcn = createCallbackFcn(app, @CalibrarCamButtonValueChanged, true);
            app.CalibrarCamButton.Text = 'Calibrar Cam';
            app.ClasificarButton = uibutton(app.UIFigure, 'state');
            app.ClasificarButton.ValueChangedFcn = createCallbackFcn(app, @ClasificarButtonValueChanged, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.CapturarPiezasButton = uibutton(app.UIFigure, 'state');
            app.CapturarPiezasButton.ValueChangedFcn = createCallbackFcn(app, @CapturarPiezasButtonValueChanged, true);
            app.CapturarPiezasButton.Text = 'Capturar Piezas';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.ParadaButton = uibutton(app.UIFigure, 'push');
            app.ParadaButton.ButtonPushedFcn = createCallbackFcn(app, @ParadaButtonPushed, true);
            app.ParadaButton.Text = 'Parada';
            app.OperacionLampLabel = uilabel(app.UIFigure);
            app.OperacionLampLabel.Text = {'Operacion'; ''};
            app.OperacionLamp = uilamp(app.UIFigure);
            app.OperacionLamp.Enable = 'off';
            app.PausaLampLabel = uilabel(app.UIFigure);
            app.PausaLampLabel.Text = {'Pausa'; ''};
            app.PausaLamp = uilamp(app.UIFigure);
            app.ParadaLabel = uilabel(app.UIFigure);
            app.ParadaLabel.Text = {'Parada'; ''};
            app.ParadaLamp = uilamp(app.UIFigure);
            app.ParadaLamp.Enable = 'off';
            app.Phantom1Panel = uipanel(app.UIFigure);
            app.Phantom1Panel.Title = 'Phantom1';
            app.EspaciodelasconfiguracionesLabel = uilabel(app.Phantom1Panel);
            app.EspaciodelasconfiguracionesLabel.Text = 'Espacio de las configuraciones';
            app.q1Label = uilabel(app.Phantom1Panel);
            app.q1Label.Text = 'q1=';
            app.q1 = uilabel(app.Phantom1Panel);
            app.q1.Text = '0';
            app.q2Label = uilabel(app.Phantom1Panel);
            app.q2Label.Text = 'q2=';
            app.q2 = uilabel(app.Phantom1Panel);
            app.q2.Text = '0';
            app.q3Label = uilabel(app.Phantom1Panel);
            app.q3Label.Text = 'q3=';
            app.q3 = uilabel(app.Phantom1Panel);
            app.q3.Text = '0';
            app.q4Label = uilabel(app.Phantom1Panel);
            app.q4Label.Text = 'q4=';
            app.q4 = uilabel(app.Phantom1Panel);
            app.q4.Text = '0';
            app.EspaciooperacionalLabel = uilabel(app.Phantom1Panel);
            app.EspaciooperacionalLabel.Text = 'Espacio operacional';
            app.xLabel = uilabel(app.Phantom1Panel);
            app.xLabel.Text = 'x=';
            app.x = uilabel(app.Phantom1Panel);
            app.x.Text = '0';
            app.yLabel = uilabel(app.Phantom1Panel);
            app.yLabel.Text = 'y=';
            app.y = uilabel(app.Phantom1Panel);
            app.y.Text = '0';
            app.zLabel = uilabel(app.Phantom1Panel);
            app.zLabel.Text = 'z=';
            app.z = uilabel(app.Phantom1Panel);
            app.z.Text = '0';
            app.RLabel = uilabel(app.Phantom1Panel);
            app.RLabel.Text = 'R=';
            app.R = uilabel(app.Phantom1Panel);
            app.R.Text = '0';
            app.PLabel = uilabel(app.Phantom1Panel);
            app.PLabel.Text = 'P=';
            app.P = uilabel(app.Phantom1Panel);
            app.P.Text = '0';
            app.YLabel = uilabel(app.Phantom1Panel);
            app.YLabel.Text = 'Y=';
            app.Y = uilabel(app.Phantom1Panel);
            app.Y.Text = '0';
            app.Phantom2Panel = uipanel(app.UIFigure);
            app.Phantom2Panel.Title = 'Phantom2';
            app.EspaciodelasconfiguracionesLabel_2 = uilabel(app.Phantom2Panel);
            app.EspaciodelasconfiguracionesLabel_2.Text = 'Espacio de las configuraciones';
            app.q1Label_2 = uilabel(app.Phantom2Panel);
            app.q1Label_2.Text = 'q1=';
            app.q2Label_2 = uilabel(app.Phantom2Panel);
            app.q2Label_2.Text = 'q2=';
            app.q2_2 = uilabel(app.Phantom2Panel);
            app.q2_2.Text = '0';
            app.q3Label_2 = uilabel(app.Phantom2Panel);
            app.q3Label_2.Text = 'q3=';
            app.q3_2 = uilabel(app.Phantom2Panel);
            app.q3_2.Text = '0';
            app.q4Label_2 = uilabel(app.Phantom2Panel);
            app.q4Label_2.Text = 'q4=';
            app.q4_2 = uilabel(app.Phantom2Panel);
            app.q4_2.Text = '0';
            app.EspaciooperacionalLabel_2 = uilabel(app.Phantom2Panel);
            app.EspaciooperacionalLabel_2.Text = 'Espacio operacional';
            app.xLabel_2 = uilabel(app.Phantom2Panel);
            app.xLabel_2.Text = 'x=';
            app.x2 = uilabel(app.Phantom2Panel);
            app.x2.Text = '0';
            app.yLabel_2 = uilabel(app.Phantom2Panel);
            app.yLabel_2.Text = 'y=';
            app.y2 = uilabel(app.Phantom2Panel);
            app.y2.Text = '0';
            app.zLabel_2 = uilabel(app.Phantom2Panel);
            app.zLabel_2.Text = 'z=';
            app.z2 = uilabel(app.Phantom2Panel);
            app.z2.Text = '0';
            app.RLabel_2 = uilabel(app.Phantom2Panel);
            app.RLabel_2.Text = 'R=';
            app.R2 = uilabel(app.Phantom2Panel);
            app.R2.Text = '0';
            app.PLabel_2 = uilabel(app.Phantom2Panel);
            app.PLabel_2.Text = 'P=';
            app.P2 = uilabel(app.Phantom2Panel);
            app.P2.Text = '0';
            app.YLabel_2 = uilabel(app.Phantom2Panel);
            app.YLabel_2.Text = 'Y=';
            app.Y2 = uilabel(app.Phantom2Panel);
            app.Y2.Text = '0';
            app.q1_2 = uilabel(app.Phantom2Panel);
            app.q1_2.Text = '0';
            app.TornilloPanel = uipanel(app.UIFigure);
            app.TornilloPanel.Title = 'Tornillo';
            app.xLabel_3 = uilabel(app.TornilloPanel);
            app.xLabel_3.Text = 'x =';
            app.xtor = uilabel(app.TornilloPanel);
            app.xtor.Text = '0';
            app.yLabel_3 = uilabel(app.TornilloPanel);
            app.yLabel_3.Text = 'y =';
            app.ytor = uilabel(app.TornilloPanel);
            app.ytor.Text = '0';
            app.RestantesLabel = uilabel(app.TornilloPanel);
            app.RestantesLabel.Text = 'Restantes =';
            app.ColocadosLabel = uilabel(app.TornilloPanel);
            app.ColocadosLabel.Text = 'Colocados = ';
            app.TornilloRestantes = uilabel(app.TornilloPanel);
            app.TornilloRestantes.Text = '0';
            app.TornillosColocados = uilabel(app.TornilloPanel);
            app.TornillosColocados.Text = '0';
            app.TuercaPanel = uipanel(app.UIFigure);
            app.TuercaPanel.Title = 'Tuerca';
            app.xLabel_4 = uilabel(app.TuercaPanel);
            app.xLabel_4.Text = 'x =';
            app.xtuer = uilabel(app.TuercaPanel);
            app.xtuer.Text = '0';
            app.yLabel_4 = uilabel(app.TuercaPanel);
            app.yLabel_4.Text = 'y =';
            app.ytuer = uilabel(app.TuercaPanel);
            app.ytuer.Text = '0';
            app.RestantesLabel_2 = uilabel(app.TuercaPanel);
            app.RestantesLabel_2.Text = 'Restantes =';
            app.ColocadosLabel_2 = uilabel(app.TuercaPanel);
            app.ColocadosLabel_2.Text = 'Colocados = ';
            app.TuercasRestantes = uilabel(app.TuercaPanel);
            app.TuercasRestantes.Text = '0';
            app.TuercasColocadas = uilabel(app.TuercaPanel);
            app.TuercasColocadas.Text = '0';
            app.LampTablero = uilamp(app.UIFigure);
            app.LampPiezas = uilamp(app.UIFigure);
            app.LampCam = uilamp(app.UIFigure);
            app.LampClas = uilamp(app.UIFigure);
            app.Ensamble = uibutton(app.UIFigure, 'push');
            app.Ensamble.ButtonPushedFcn = createCallbackFcn(app, @EnsambleButtonPushed, true);
            app.Ensamble.Text = 'Ensamble';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_3 = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end