classdef appVision < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        CapturarTableroButton      matlab.ui.control.StateButton
        CalibrarCamButton          matlab.ui.control.StateButton
        CapturarPiezasButton       matlab.ui.control.StateButton
        ClasificarButton           matlab.ui.control.StateButton
        CargarPiezasButton         matlab.ui.control.StateButton
        InicioButton               matlab.ui.control.Button
        ParadaButton               matlab.ui.control.Button
        OperacionLampLabel         matlab.ui.control.Label
        OperacionLamp              matlab.ui.control.Lamp
        PausaLampLabel             matlab.ui.control.Label
        PausaLamp                  matlab.ui.control.Lamp
        ParadaLabel                matlab.ui.control.Label
        ParadaLamp                 matlab.ui.control.Lamp
        Phantom1Panel              matlab.ui.container.Panel
        EspaciodelasconfiguracionesLabel  matlab.ui.control.Label
        q1Label                    matlab.ui.control.Label
        q1                         matlab.ui.control.Label
        q2Label                    matlab.ui.control.Label
        q2                         matlab.ui.control.Label
        q3Label                    matlab.ui.control.Label
        q3                         matlab.ui.control.Label
        q4Label                    matlab.ui.control.Label
        q4                         matlab.ui.control.Label
        EspaciooperacionalLabel    matlab.ui.control.Label
        xLabel                     matlab.ui.control.Label
        x                          matlab.ui.control.Label
        yLabel                     matlab.ui.control.Label
        y                          matlab.ui.control.Label
        zLabel                     matlab.ui.control.Label
        z                          matlab.ui.control.Label
        RLabel                     matlab.ui.control.Label
        R                          matlab.ui.control.Label
        PLabel                     matlab.ui.control.Label
        P                          matlab.ui.control.Label
        YLabel                     matlab.ui.control.Label
        Y                          matlab.ui.control.Label
        Phantom2Panel              matlab.ui.container.Panel
        EspaciodelasconfiguracionesLabel_2  matlab.ui.control.Label
        q1Label_2                  matlab.ui.control.Label
        q2Label_2                  matlab.ui.control.Label
        q2_2                       matlab.ui.control.Label
        q3Label_2                  matlab.ui.control.Label
        q3_2                       matlab.ui.control.Label
        q4Label_2                  matlab.ui.control.Label
        q4_2                       matlab.ui.control.Label
        EspaciooperacionalLabel_2  matlab.ui.control.Label
        xLabel_2                   matlab.ui.control.Label
        x2                         matlab.ui.control.Label
        yLabel_2                   matlab.ui.control.Label
        y2                         matlab.ui.control.Label
        zLabel_2                   matlab.ui.control.Label
        z2                         matlab.ui.control.Label
        RLabel_2                   matlab.ui.control.Label
        R2                         matlab.ui.control.Label
        PLabel_2                   matlab.ui.control.Label
        P2                         matlab.ui.control.Label
        YLabel_2                   matlab.ui.control.Label
        Y2                         matlab.ui.control.Label
        q1_2                       matlab.ui.control.Label
        TornilloPanel              matlab.ui.container.Panel
        xLabel_3                   matlab.ui.control.Label
        xtor                       matlab.ui.control.Label
        yLabel_3                   matlab.ui.control.Label
        ytor                       matlab.ui.control.Label
        TuercaPanel                matlab.ui.container.Panel
        xLabel_4                   matlab.ui.control.Label
        xtuer                      matlab.ui.control.Label
        yLabel_4                   matlab.ui.control.Label
        ytuer                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        Ipiezas % Description
        params 
        estimationErrors
        RCal
        TCal
        xyTuercas
        xyTornillos
        Ic
        centrTuercas
        centrTornillos
        target
        Path= "C:\Users\Alejandra\Documents\GitHub\Pruebas\Scripts Matlab";
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalibrarCamButton
        function CalibrarCamButtonValueChanged(app, event)
            [app.params,app.estimationErrors,app.RCal,app.TCal,app.target]=calibrar(app.Path);    
            imshow(app.target,'parent',app.UIAxes);
        end

        % Value changed function: ClasificarButton
        function ClasificarButtonValueChanged(app, event)
         
            [app.xyTuercas, app.xyTornillos,app.Ic,app.centrTuercas,app.centrTornillos]=clasificar(app.Ipiezas,app.params,app.RCal,app.TCal); 
            %% Gráfico Clasificación
            n1=size(app.xyTuercas);
            n2=size(app.xyTornillos);            
            imshow(app.Ipiezas,'parent',app.UIAxes);
            imshow(app.Ic,'parent',app.UIAxes_2);
            for n=1:n1(1)
                 text(app.centrTuercas(n,1),app.centrTuercas(n,2),num2str(n),'FontSize',5,'Color','g','parent',app.UIAxes_2);
            end
            for n=1:n2(1)
                 text(app.centrTornillos(n,1),app.centrTornillos(n,2),num2str(n),'FontSize',5,'Color','r','parent',app.UIAxes_2);
            end
        end

        % Value changed function: CargarPiezasButton
        function CargarPiezasButtonValueChanged(app, event)
            app.Ipiezas=cargarImagen(app.Path);
            imshow(app.Ipiezas,'parent',app.UIAxes);
        end

        % Callback function
        function PathTextAreaValueChanged(app, event)
                   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            zlabel(app.UIAxes, '')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            zlabel(app.UIAxes_2, '')
            app.CapturarTableroButton = uibutton(app.UIFigure, 'state');
            app.CapturarTableroButton.Text = 'Capturar Tablero';
            app.CalibrarCamButton = uibutton(app.UIFigure, 'state');
            app.CalibrarCamButton.ValueChangedFcn = createCallbackFcn(app, @CalibrarCamButtonValueChanged, true);
            app.CalibrarCamButton.Text = 'Calibrar Cam';
            app.CapturarPiezasButton = uibutton(app.UIFigure, 'state');
            app.CapturarPiezasButton.Text = 'Capturar Piezas';
            app.ClasificarButton = uibutton(app.UIFigure, 'state');
            app.ClasificarButton.ValueChangedFcn = createCallbackFcn(app, @ClasificarButtonValueChanged, true);
            app.ClasificarButton.Text = 'Clasificar';
            app.CargarPiezasButton = uibutton(app.UIFigure, 'state');
            app.CargarPiezasButton.ValueChangedFcn = createCallbackFcn(app, @CargarPiezasButtonValueChanged, true);
            app.CargarPiezasButton.Text = 'Cargar Piezas';
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.Text = 'Inicio';
            app.ParadaButton = uibutton(app.UIFigure, 'push');
            app.ParadaButton.Text = 'Parada';
            app.OperacionLampLabel = uilabel(app.UIFigure);
            app.OperacionLampLabel.Text = {'Operacion'; ''};
            app.OperacionLamp = uilamp(app.UIFigure);
            app.PausaLampLabel = uilabel(app.UIFigure);
            app.PausaLampLabel.Text = {'Pausa'; ''};
            app.PausaLamp = uilamp(app.UIFigure);
            app.ParadaLabel = uilabel(app.UIFigure);
            app.ParadaLabel.Text = {'Parada'; ''};
            app.ParadaLamp = uilamp(app.UIFigure);
            app.Phantom1Panel = uipanel(app.UIFigure);
            app.Phantom1Panel.Title = 'Phantom1';
            app.EspaciodelasconfiguracionesLabel = uilabel(app.Phantom1Panel);
            app.EspaciodelasconfiguracionesLabel.Text = 'Espacio de las configuraciones';
            app.q1Label = uilabel(app.Phantom1Panel);
            app.q1Label.Text = 'q1=';
            app.q1 = uilabel(app.Phantom1Panel);
            app.q1.Text = '0';
            app.q2Label = uilabel(app.Phantom1Panel);
            app.q2Label.Text = 'q2=';
            app.q2 = uilabel(app.Phantom1Panel);
            app.q2.Text = '0';
            app.q3Label = uilabel(app.Phantom1Panel);
            app.q3Label.Text = 'q3=';
            app.q3 = uilabel(app.Phantom1Panel);
            app.q3.Text = '0';
            app.q4Label = uilabel(app.Phantom1Panel);
            app.q4Label.Text = 'q4=';
            app.q4 = uilabel(app.Phantom1Panel);
            app.q4.Text = '0';
            app.EspaciooperacionalLabel = uilabel(app.Phantom1Panel);
            app.EspaciooperacionalLabel.Text = 'Espacio operacional';
            app.xLabel = uilabel(app.Phantom1Panel);
            app.xLabel.Text = 'x=';
            app.x = uilabel(app.Phantom1Panel);
            app.x.Text = '0';
            app.yLabel = uilabel(app.Phantom1Panel);
            app.yLabel.Text = 'y=';
            app.y = uilabel(app.Phantom1Panel);
            app.y.Text = '0';
            app.zLabel = uilabel(app.Phantom1Panel);
            app.zLabel.Text = 'z=';
            app.z = uilabel(app.Phantom1Panel);
            app.z.Text = '0';
            app.RLabel = uilabel(app.Phantom1Panel);
            app.RLabel.Text = 'R=';
            app.R = uilabel(app.Phantom1Panel);
            app.R.Text = '0';
            app.PLabel = uilabel(app.Phantom1Panel);
            app.PLabel.Text = 'P=';
            app.P = uilabel(app.Phantom1Panel);
            app.P.Text = '0';
            app.YLabel = uilabel(app.Phantom1Panel);
            app.YLabel.Text = 'Y=';
            app.Y = uilabel(app.Phantom1Panel);
            app.Y.Text = '0';
            app.Phantom2Panel = uipanel(app.UIFigure);
            app.Phantom2Panel.Title = 'Phantom2';
            app.EspaciodelasconfiguracionesLabel_2 = uilabel(app.Phantom2Panel);
            app.EspaciodelasconfiguracionesLabel_2.Text = 'Espacio de las configuraciones';
            app.q1Label_2 = uilabel(app.Phantom2Panel);
            app.q1Label_2.Text = 'q1=';
            app.q2Label_2 = uilabel(app.Phantom2Panel);
            app.q2Label_2.Text = 'q2=';
            app.q2_2 = uilabel(app.Phantom2Panel);
            app.q2_2.Text = '0';
            app.q3Label_2 = uilabel(app.Phantom2Panel);
            app.q3Label_2.Text = 'q3=';
            app.q3_2 = uilabel(app.Phantom2Panel);
            app.q3_2.Text = '0';
            app.q4Label_2 = uilabel(app.Phantom2Panel);
            app.q4Label_2.Text = 'q4=';
            app.q4_2 = uilabel(app.Phantom2Panel);
            app.q4_2.Text = '0';
            app.EspaciooperacionalLabel_2 = uilabel(app.Phantom2Panel);
            app.EspaciooperacionalLabel_2.Text = 'Espacio operacional';
            app.xLabel_2 = uilabel(app.Phantom2Panel);
            app.xLabel_2.Text = 'x=';
            app.x2 = uilabel(app.Phantom2Panel);
            app.x2.Text = '0';
            app.yLabel_2 = uilabel(app.Phantom2Panel);
            app.yLabel_2.Text = 'y=';
            app.y2 = uilabel(app.Phantom2Panel);
            app.y2.Text = '0';
            app.zLabel_2 = uilabel(app.Phantom2Panel);
            app.zLabel_2.Text = 'z=';
            app.z2 = uilabel(app.Phantom2Panel);
            app.z2.Text = '0';
            app.RLabel_2 = uilabel(app.Phantom2Panel);
            app.RLabel_2.Text = 'R=';
            app.R2 = uilabel(app.Phantom2Panel);
            app.R2.Text = '0';
            app.PLabel_2 = uilabel(app.Phantom2Panel);
            app.PLabel_2.Text = 'P=';
            app.P2 = uilabel(app.Phantom2Panel);
            app.P2.Text = '0';
            app.YLabel_2 = uilabel(app.Phantom2Panel);
            app.YLabel_2.Text = 'Y=';
            app.Y2 = uilabel(app.Phantom2Panel);
            app.Y2.Text = '0';
            app.q1_2 = uilabel(app.Phantom2Panel);
            app.q1_2.Text = '0';
            app.TornilloPanel = uipanel(app.UIFigure);
            app.TornilloPanel.Title = 'Tornillo';
            app.xLabel_3 = uilabel(app.TornilloPanel);
            app.xLabel_3.Text = 'x=';
            app.xtor = uilabel(app.TornilloPanel);
            app.xtor.Text = '0';
            app.yLabel_3 = uilabel(app.TornilloPanel);
            app.yLabel_3.Text = 'y=';
            app.ytor = uilabel(app.TornilloPanel);
            app.ytor.Text = '0';
            app.TuercaPanel = uipanel(app.UIFigure);
            app.TuercaPanel.Title = 'Tuerca';
            app.xLabel_4 = uilabel(app.TuercaPanel);
            app.xLabel_4.Text = 'x=';
            app.xtuer = uilabel(app.TuercaPanel);
            app.xtuer.Text = '0';
            app.yLabel_4 = uilabel(app.TuercaPanel);
            app.yLabel_4.Text = 'y=';
            app.ytuer = uilabel(app.TuercaPanel);
            app.ytuer.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appVision

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end